classdef appactivation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StatusText                  matlab.ui.control.Label
        EmailText                   matlab.ui.control.Label
        LicenseText                 matlab.ui.control.Label
        ActivateButton              matlab.ui.control.Button
        EmailAddressEditField       matlab.ui.control.EditField
        EmailAddressEditFieldLabel  matlab.ui.control.Label
        LicenseKeyEditField         matlab.ui.control.EditField
        LicenseKeyEditFieldLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        MainApp;
        AppName;
        UserName;
        RepoName;
        Token;
        AppIcon;
        IsLicenseFormatValid = false;
        IsEmailFormatValid = false;
        GreenHexCode = '#77ac30';
    end

    methods (Access = private)

        function Validity = checkLicenseFormatValidity(app, EnteredLicense)
            LicensePattern = alphanumericsPattern(4) + "-" + alphanumericsPattern(4) + "-" + alphanumericsPattern(4) + "-" + alphanumericsPattern(4);
            Validity = matches(EnteredLicense, LicensePattern);
        end

        function Validity = checkEmailFormatValidity(app, EnteredEmail)
            Validity = contains(EnteredEmail, '@');
        end

        function enableActivateButton(app)
            if app.IsLicenseFormatValid && app.IsEmailFormatValid
                app.ActivateButton.Enable = 'on';
            else
                app.ActivateButton.Enable = 'off';
            end
        end

        function delayedClearText(app, TextObject, Delay)
            arguments
                app
                TextObject         matlab.ui.control.Label
                Delay = 3
            end
            StatusTimer = timer(StartDelay = Delay, ...
                TimerFcn = @(~, ~) set(TextObject, Text = ''));
            start(StatusTimer);
        end

        function delayedClearAllText(app, Delay)
            arguments
                app
                Delay = 3
            end
            cellfun(@(x) delayedClearText(app, x, Delay), {app.EmailText, app.LicenseText, app.StatusText});
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, UserName, RepoName, Token)
            arguments
                app
                MainApp
                UserName
                RepoName
                Token
            end
            centerAppWindow(app);
            app.MainApp = MainApp;
            MainAppUIFigure = findUIFigure(app.MainApp); 
            app.AppName = MainAppUIFigure.Name;
            app.UserName = UserName;
            app.RepoName = RepoName;
            app.Token = Token;
            MainAppUIFigure.Name = [app.AppName, ' License Activation'];
            if ~isempty(MainAppUIFigure.Icon)
                app.UIFigure.Icon = MainAppUIFigure.Icon;
            end
        end

        % Value changed function: LicenseKeyEditField
        function LicenseKeyEditFieldValueChanged(app, event)
            app.IsLicenseFormatValid = checkLicenseFormatValidity(app, app.LicenseKeyEditField.Value);
            if app.IsLicenseFormatValid
                set(app.LicenseText, Text = '');
            else
                set(app.LicenseText, Text = 'License must be in XXXX-XXXX-XXXX-XXXX format', FontColor = 'red');
            end
            if isempty(app.LicenseKeyEditField.Value)
                set(app.LicenseText, Text = '');
            end
            app.LicenseKeyEditField.Value = upper(app.LicenseKeyEditField.Value);
            enableActivateButton(app);
        end

        % Value changed function: EmailAddressEditField
        function EmailAddressEditFieldValueChanged(app, event)
            app.IsEmailFormatValid = checkEmailFormatValidity(app, app.EmailAddressEditField.Value);
            if app.IsEmailFormatValid
                set(app.EmailText, Text = '');
            else
                set(app.EmailText, Text = 'Please enter a valid email address', FontColor = 'red');
            end
            if isempty(app.EmailAddressEditField.Value)
                set(app.EmailText, Text = '');
            end
            enableActivateButton(app);
        end

        % Button pushed function: ActivateButton
        function ActivateButtonPushed(app, event)
            [app.LicenseText.Text, app.EmailText.Text, app.StatusText.Text] = deal('');

            % Try retrieving license database from GitHub
            try Lic = delimited2table(githubGet(app.UserName, app.RepoName, 'license-database.csv', Token = app.Token));
                set(app.StatusText, Text = 'Activating license...', FontColor = 'k');
                drawnow;
            catch
                set(app.StatusText, Text = 'Internet connection failure', FontColor = 'r');
                delayedClearAllText(app)
                return;
            end

            % Find the license key in the database
            EnteredLicense = string(app.LicenseKeyEditField.Value);
            MatchedLicenseIdx = find(Lic.LicenseKey == EnteredLicense);
            if isempty(MatchedLicenseIdx)
                set(app.LicenseText, Text = 'License key is not valid', FontColor = 'r');
                delayedClearAllText(app)
                return;
            end

            % Check if the license has been activated on another device
            if Lic.DeviceID(MatchedLicenseIdx) ~= ""
                set(app.LicenseText, Text = 'The license has been activated on another device', FontColor = 'r');
                delayedClearAllText(app)
                return;
            end

            % Check license validity date
            ValidUntil = datetime(Lic.ValidUntil(MatchedLicenseIdx));
            if Lic.ValidUntil(MatchedLicenseIdx) < datetime
                set(app.LicenseText, Text = sprintf('License key has expired since %s', ValidUntil), FontColor = 'r');
                delayedClearAllText(app)
                return;
            end
            set(app.LicenseText, Text = sprintf('License key is valid until %s', ValidUntil), FontColor = app.GreenHexCode);

            % Check the email associated with the email
            Email = Lic.UserEmail(MatchedLicenseIdx);
            EnteredEmail = string(app.EmailAddressEditField.Value);
            if Email ~= EnteredEmail
                set(app.EmailText, Text = 'The license is assigned to a different email address', FontColor = 'r');
                delayedClearAllText(app)
                return;
            end
            set(app.EmailText, Text = 'The license matches with the email address', FontColor = app.GreenHexCode);

            % Update the license database with the newly generated device ID and try uploading to GitHub
            DeviceID = generateDeviceId;
            Lic.DeviceID(MatchedLicenseIdx) = string(DeviceID);
            writetable(Lic, 'license-database.csv'); % (!) WIP - POSSIBLE VULNERABILITY
            try githubPut(app.UserName, app.RepoName, 'license-database.csv', 'license-database.csv', ...
                    app.Token, CommitMessage = sprintf('Device %s activated', DeviceID));
                set(app.StatusText, Text = 'License is activated!', FontColor = app.GreenHexCode);
            catch ME
                set(app.StatusText, Text = 'License activation unsuccessful', FontColor = 'r');
            end
            drawnow;

            % Clear the contents of the 'license-database' file and delete
            writematrix('', 'license-database.csv');
            movefile('license-database.csv', 'tmp');
            delete('tmp');
            delayedClearAllText(app, 5);
            pause(5);
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.MainApp)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LicenseKeyEditFieldLabel = uilabel(app.UIFigure);
            app.LicenseKeyEditFieldLabel.Text = 'License Key';
            app.LicenseKeyEditField = uieditfield(app.UIFigure, 'text');
            app.LicenseKeyEditField.ValueChangedFcn = createCallbackFcn(app, @LicenseKeyEditFieldValueChanged, true);
            app.EmailAddressEditFieldLabel = uilabel(app.UIFigure);
            app.EmailAddressEditFieldLabel.Text = 'Email Address';
            app.EmailAddressEditField = uieditfield(app.UIFigure, 'text');
            app.EmailAddressEditField.ValueChangedFcn = createCallbackFcn(app, @EmailAddressEditFieldValueChanged, true);
            app.ActivateButton = uibutton(app.UIFigure, 'push');
            app.ActivateButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateButtonPushed, true);
            app.ActivateButton.Enable = 'off';
            app.ActivateButton.Text = 'Activate';
            app.LicenseText = uilabel(app.UIFigure);
            app.LicenseText.Text = '';
            app.EmailText = uilabel(app.UIFigure);
            app.EmailText.Text = '';
            app.StatusText = uilabel(app.UIFigure);
            app.StatusText.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appactivation(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end